classdef poc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        AnnotateSpeechButton      matlab.ui.control.Button
        NoFileSelectedLabel       matlab.ui.control.Label
        AudioFileLabel            matlab.ui.control.Label
        SpokenAudioTextArea       matlab.ui.control.TextArea
        SpokenAudioTextAreaLabel  matlab.ui.control.Label
        SelectFileButton          matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function selectFile(app, event)
            [file, path] = uigetfile('*.*', 'Select an audio file');
            app.NoFileSelectedLabel.Text = string(path) + string(file);
        end

        % Button pushed function: AnnotateSpeechButton
        function annotateSpeechButton(app, event)
            % Try to load the model
            try
                load('speech_processing_model.mat', 'speech_processing_model');
            catch
                warning('Speech Model was not found in this directory.');
                return;
            end
            
            % Try to load the audio file
            try
                [audio, fs] = audioread(app.NoFileSelectedLabel.Text);
            catch
                warning('Audio file was not found at the specified directory, or the file was not compatible with the system.');
                return;
            end
            
            % Process the audio file
            annotated_speakers = speech_processing_model.annotateAudio(audio, fs, 0.5);
            % Display the results to the plot
            cla(app.UIAxes);
            speech_processing_model.visualizeResults(audio, fs, annotated_speakers, app.UIAxes);
            % Grab the speech seperated by the speakers
            speech_list = speech_processing_model.printAnnontation(annotated_speakers);
            
            % Loop through and display the speakers to the text area
            app.SpokenAudioTextArea.Value = speech_list;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {365, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.SelectFileButton.Text = 'Select File';
            app.SpokenAudioTextAreaLabel = uilabel(app.LeftPanel);
            app.SpokenAudioTextAreaLabel.Text = 'Spoken Audio';
            app.SpokenAudioTextArea = uitextarea(app.LeftPanel);
            app.AudioFileLabel = uilabel(app.LeftPanel);
            app.AudioFileLabel.Text = 'Audio File: ';
            app.NoFileSelectedLabel = uilabel(app.LeftPanel);
            app.NoFileSelectedLabel.Text = 'No File Selected!';
            app.AnnotateSpeechButton = uibutton(app.LeftPanel, 'push');
            app.AnnotateSpeechButton.ButtonPushedFcn = createCallbackFcn(app, @annotateSpeechButton, true);
            app.AnnotateSpeechButton.Text = 'Annotate Speech';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Speaker Diarization')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end